classdef ByrdApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        IceCoreDropDownLabel_2          matlab.ui.control.Label
        BibTeXButton                    matlab.ui.control.Button
        PlainTextButton                 matlab.ui.control.Button
        DataSetDropDown                 matlab.ui.control.DropDown
        DataSetDropDownLabel            matlab.ui.control.Label
        OriginalReferenceTextArea       matlab.ui.control.TextArea
        OriginalReferenceTextAreaLabel  matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        PublicationDropDown             matlab.ui.control.DropDown
        PublicationDropDownLabel        matlab.ui.control.Label
        IceCoreDropDown                 matlab.ui.control.DropDown
        IceCoreDropDownLabel            matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        RightPanel                      matlab.ui.container.Panel
        LineStyleDropDown               matlab.ui.control.DropDown
        LineStyleDropDownLabel          matlab.ui.control.Label
        LineWidthSpinner                matlab.ui.control.Spinner
        LineWidthSpinnerLabel           matlab.ui.control.Label
        MovingAvgSlider                 matlab.ui.control.Slider
        MovingAvgSliderLabel            matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        MapViewButton                   matlab.ui.control.Button
        LocationTextArea                matlab.ui.control.TextArea
        LocationTextAreaLabel           matlab.ui.control.Label
        ChartStyleLabel                 matlab.ui.control.Label
        YDataLabel                      matlab.ui.control.Label
        XDataLabel                      matlab.ui.control.Label
        SaveasButton_2                  matlab.ui.control.Button
        ParametersDropDown_3            matlab.ui.control.DropDown
        ParametersDropDown_2            matlab.ui.control.DropDown
        ParametersDropDown              matlab.ui.control.DropDown
        ParametersDropDownLabel         matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        SaveasButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ClearButton                     matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        OrangeButton                    matlab.ui.control.RadioButton
        ColorLabel                      matlab.ui.control.Label
        BlackButton                     matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        RedButton                       matlab.ui.control.RadioButton
        PurpleButton                    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IceCoreDropDown
        function IceCoreDropDownValueChanged(app, event)
			%
			% When the value of the 'Ice Core' drop-down menu is
			% changed, the following code will run. This function
			% determines the values that will auto-populate the 'Publication' 
			% drop-down menu.
			%
    
            % Add path to ICP_get_publication_names() function
            p = [pwd '/Functions/'];
            addpath(p)

			none = {'None'};
			
			switch app.IceCoreDropDown.Value
				case 'Bona-Churchill'
					items = ICP_get_publication_names('bona-churchill');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Bruce Plateau'
					items = ICP_get_publication_names('bruce-plateau');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Dasuopu'
					items = ICP_get_publication_names('dasuopu');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Dunde'
					items = ICP_get_publication_names('dunde');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Elbrus'
					items = ICP_get_publication_names('elbrus');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Guliya'
					items = ICP_get_publication_names('guliya');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'IPO Reconstruction'
					items = ICP_get_publication_names('interdecadal-pacific-oscillation-index');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Kilimanjaro'
					items = ICP_get_publication_names('kilimanjaro');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Nevado Huascaran'
					items = ICP_get_publication_names('nevado-huascaran');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Plateau Remote'
					items = ICP_get_publication_names('plateau-remote');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Prince of Wales'
					items = ICP_get_publication_names('prince-of-wales');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Puncak Jaya'
					items = ICP_get_publication_names('puncak-jaya');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Puruogangri'
					items = ICP_get_publication_names('puruogangri');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Quelccaya'
					items = ICP_get_publication_names('quelccaya');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Sajama'
					items = ICP_get_publication_names('sajama');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Siple Station'
					items = ICP_get_publication_names('siple-station');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Taylor Dome'
					items = ICP_get_publication_names('taylor-dome');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'Windy Dome'
					items = ICP_get_publication_names('windy-dome');
					app.PublicationDropDown.Items = vertcat(none,items);

				case 'None'
					app.PublicationDropDown.Items = {'None'};
					app.DataSetDropDown.Items = {'None'};

				otherwise
					app.PublicationDropDown.Items = "";
			end

        end

        % Value changed function: PublicationDropDown
        function PublicationDropDownValueChanged(app, event)
			%
			% When the value of the 'Publication' drop-down menu is
			% changed, the following code will run. This function
			% determines the values that will auto-populate the 'Data Set' 
			% drop-down menu.
			%

            % Add path to ICP_get_dataset_names() function
            p = [pwd '/Functions/'];
            addpath(p)
			
			none = {'None'};

			switch app.PublicationDropDown.Value
				case 'Sierra-Hernández et al. (2022)'
					items = ICP_get_dataset_names('Sierra-Hernández et al. (2022)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Porter et al. (2019)'
					items = ICP_get_dataset_names('Porter et al. (2019)');
					app.DataSetDropDown.Items = vertcat(none,items);
					
				case 'Goodwin et al. (2016)'
					items = ICP_get_dataset_names('Goodwin et al. (2016)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Gabrielli et al. (2020)'
					items = ICP_get_dataset_names('Gabrielli et al. (2020)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2000)'
					items = ICP_get_dataset_names('Thompson et al. (2000)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2006a)'
					items = ICP_get_dataset_names('Thompson et al. (2006a)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Mikhalenko et al. (2023)'
					items = ICP_get_dataset_names('Mikhalenko et al. (2023)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2024)'
					items = ICP_get_dataset_names('Thompson et al. (2024)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2022)'
					items = ICP_get_dataset_names('Thompson et al. (2022)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Sierra-Hernández et al. (2019)'
					items = ICP_get_dataset_names('Sierra-Hernández et al. (2019)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Sierra-Hernández et al. (2018)'
					items = ICP_get_dataset_names('Sierra-Hernández et al. (2018)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Kutuzov et al. (2018)'
					items = ICP_get_dataset_names('Kutuzov et al. (2018)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2018)'
					items = ICP_get_dataset_names('Thompson et al. (2018)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (1997)'
					items = ICP_get_dataset_names('Thompson et al. (1997)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Porter et al. (2021)'
					items = ICP_get_dataset_names('Porter et al. (2021)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2002)'
					items = ICP_get_dataset_names('Thompson et al. (2002)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Weber et al. (2023)'
					items = ICP_get_dataset_names('Weber et al. (2023)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (1995)'
					items = ICP_get_dataset_names('Thompson et al. (1995)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'PAGES (2013)'
					items = ICP_get_dataset_names('PAGES (2013)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Mosley-Thompson (1996)'
					items = ICP_get_dataset_names('Mosley-Thompson (1996)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Kinnard et al. (2011a)'
					items = ICP_get_dataset_names('Kinnard et al. (2011a)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Permana et al. (2019)'
					items = ICP_get_dataset_names('Permana et al. (2019)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Beaudon et al. (2017)'
					items = ICP_get_dataset_names('Beaudon et al. (2017)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2006b)'
					items = ICP_get_dataset_names('Thompson et al. (2006b)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Uglietti et al. (2015)'
					items = ICP_get_dataset_names('Uglietti et al. (2015)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (2013)'
					items = ICP_get_dataset_names('Thompson et al. (2013)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (1984)'
					items = ICP_get_dataset_names('Thompson et al. (1984)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Thompson et al. (1998)'
					items = ICP_get_dataset_names('Thompson et al. (1998)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Mosley-Thompson et al. (1990)'
					items = ICP_get_dataset_names('Mosley-Thompson et al. (1990)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'Aarons et al. (2016)'
					items = ICP_get_dataset_names('Aarons et al. (2016)');
					app.DataSetDropDown.Items = vertcat(none,items);
					
				case 'Kinnard et al. (2011b)'
					items = ICP_get_dataset_names('Kinnard et al. (2011b)');
					app.DataSetDropDown.Items = vertcat(none,items);

				case 'None'
					app.DataSetDropDown.Items = none;

				otherwise
					app.DataSetDropDown.Items = "";
			end
			
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
			% 
			% The following block of code is run when the "Import" button
			% is clicked. This function will print the citation information
			% for the selected dataset within the text area and also import
			% the data into the table. Moreover, this function will
			% auto-populate the dropdown menus for 'X-Data' and 'Y-Data'
			% (used for plotting purposes).
			% 

			%
			% Import data into table
			%
			if strcmp(app.IceCoreDropDown.Value,'None')
				% Do nothing
			else
				%
				% Get path to data
				%
				pathname = [pwd '/Data/'];
				extension = '.xlsx';
				switch app.IceCoreDropDown.Value
					case 'Bona-Churchill'
						filename = [pathname,'bona-churchill',extension];
					case 'Bruce Plateau'
						filename = [pathname,'bruce-plateau',extension];
					case 'Dasuopu'
						filename = [pathname,'dasuopu',extension];
					case 'Dunde'
						filename = [pathname,'dunde',extension];
					case 'Elbrus'
						filename = [pathname,'elbrus',extension];
					case 'Guliya'
						filename = [pathname,'guliya',extension];
					case 'IPO Reconstruction'
						filename = [pathname,'interdecadal-pacific-oscillation-index',extension];
					case 'Kilimanjaro'
						filename = [pathname,'kilimanjaro',extension];
					case 'Nevado Huascaran'
						filename = [pathname,'nevado-huascaran',extension];
					case 'Plateau Remote'
						filename = [pathname,'plateau-remote',extension];
					case 'Prince of Wales'
						filename = [pathname,'prince-of-wales',extension];
					case 'Puncak Jaya'
						filename = [pathname,'puncak-jaya',extension];
					case 'Puruogangri'
						filename = [pathname,'puruogangri',extension];
					case 'Quelccaya'
						filename = [pathname,'quelccaya',extension];
					case 'Sajama'
						filename = [pathname,'sajama',extension];
					case 'Siple Station'
						filename = [pathname,'siple-station',extension];
					case 'Taylor Dome'
						filename = [pathname,'taylor-dome',extension];
					case 'Windy Dome'
						filename = [pathname,'windy-dome',extension];
				end

			sheetname = app.DataSetDropDown.Value;
			[data,lat,lon,el,~,citation] = readICP(filename,sheetname);
			varnames = data.Properties.VariableNames;
			app.UITable.Data = data;
			app.UITable.ColumnName = varnames;

			% 
			% Print original reference citation
			% 
			app.OriginalReferenceTextArea.Value = sprintf(citation);

			%
			% Print latitude, longitude, and elevation
			%
			if size(lat,2) > 1 || size(lon,2) > 1
				app.LocationTextArea.Value = 'More than one latitude and/or longitude';
			elseif size(el,2) > 1
				latitude = [sprintf('%0.1f',lat),'°N, '];
				longitude = [sprintf('%0.1f',lon),'°E, '];
				elevation = [sprintf('%g',el(1)),' to ',sprintf('%g',el(end)),' masl'];
				app.LocationTextArea.Value = [latitude,longitude,elevation];
			else
				latitude = [sprintf('%0.1f',lat),'°N, '];
				longitude = [sprintf('%0.1f',lon),'°E, '];
				elevation = [sprintf('%g',el),' masl'];
				app.LocationTextArea.Value = [latitude,longitude,elevation];
			end
			

			%
			% Populate the X-Data and Y-Data dropdown menus
			%
			% Check to see if Taylor Dome data was selected
				if strcmp(app.IceCoreDropDown.Value,'Taylor Dome') && strcmp(app.PublicationDropDown.Value,'dust-radiogenic-full-digestion')
					% If true, delete the first two columns in the dataset
					app.ParametersDropDown.Items = varnames(3:end);
					app.ParametersDropDown_2.Items = varnames(3:end);
				elseif strcmp(app.IceCoreDropDown.Value,'Taylor Dome') && strcmp(app.PublicationDropDown.Value,'dust-radiogenic-acid-leach')
					app.ParametersDropDown.Items = varnames(2:end);
					app.ParametersDropDown_2.Items = varnames(2:end);
				else
					app.ParametersDropDown.Items = varnames; % X-Data
					app.ParametersDropDown_2.Items = varnames; % Y-Data
				end
			
			% End if-statement for the whole function	
			end
			

        end

        % Button pushed function: PlainTextButton
        function PlainTextButtonPushed(app, event)
            % Get the text from the Text Area
             text = app.OriginalReferenceTextArea.Value;
            % Convert from cell to string
             text = cell2mat(text);
            % Copy the text to the clipboard
             clipboard('copy', text);
        end

        % Button pushed function: BibTeXButton
        function BibTeXButtonPushed(app, event)
            % Add path to functions
            p = [pwd '/Functions/'];
            addpath(p)
            % Get the text from the Text Area
             text = app.OriginalReferenceTextArea.Value;
            % Convert from cell to string
             text = string(cell2mat(text));
            % Convert from plain text to BibTeX using custom algorithm
             bib = str2bib(text);
            % Copy the text to the clipboard
             clipboard('copy', bib);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
			%
			% This function saves the data shown in the table as a
			% MATLAB binary file (.csv) to the current folder.
			%
			% Get name of core
			core_name = app.IceCoreDropDown.Value;
			% Get name of dataset
			dataset_name = app.DataSetDropDown.Value;
			% Concatenate into file name
			new_name = [core_name,'-',dataset_name];
			save_file_as_name = [new_name,'.csv'];
			
			% Save file to current folder
			writetable(app.UITable.Data,save_file_as_name)
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
			% 
			% This function opens a dialog box for the user to save the
			% data shown in the table.
			% 
			% Open dialog box
			user_choice = questdlg('Choose a file type:', ...
				'Save as...', ...
				'CSV','Excel','Text','CSV'); % 'CSV' is default selection
			% Handle response
			if isempty(user_choice)
				return; % i.e., the user hit 'cancel' so end the loop
			else
				switch user_choice
    				case 'CSV'
        				ext = '.csv';
					case 'Excel'
        				ext = '.xlsx';
					case 'Text'
        				ext = '.txt';
				end
				% Get name of core
				core_name = app.IceCoreDropDown.Value;
				% Get name of dataset
				dataset_name = app.DataSetDropDown.Value;
				% Concatenate into file name
				save_file_as_name = [core_name,'_',dataset_name,ext];
	
				% Save file to current folder
				writetable(app.UITable.Data,save_file_as_name)
			end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
			%
			% This function plots the x- and y-data within the axes pane
			% and displays it with the selected chart style.
			%
			% Define default colors
				% Check status of radio buttons
				if app.PurpleButton.Value
					colo = '#7f55ff'; % light purple
					cont = '#5922ff'; % darker variant
				elseif app.RedButton.Value
					colo = '#e34234'; % vermillion
					cont = '#df2e1f'; % darker variant
				elseif app.BlueButton.Value
					colo = '#4169e1'; % royal blue
					cont = '#214cce'; % darker variant
				elseif app.GreenButton.Value
					colo = '#2e8b57'; % sea green
					cont = '#21653f'; % darker variant
				elseif app.BlackButton.Value
					colo = '#343837'; % Charcoal
					cont = '#050505'; % Darker variant
				elseif app.OrangeButton.Value
					colo = '#ff9408'; % tangerine
					cont = '#f0833a'; % darker variant
				end

			% Get Line Width and Line Style
			lw = app.LineWidthSpinner.Value;
			ls = app.LineStyleDropDown.Value;
			switch ls
				case 'Line'
					ls = '-';
				case 'Dash'
					ls = '--';
				case 'Dash-Dot'
					ls = '-.';
				case 'Dot'
					ls = ':';
			end

			% Get data values
			X = app.ParametersDropDown.Value;
			Y = app.ParametersDropDown_2.Value;
			plt_name = app.ParametersDropDown_3.Value;
			% Get variable names
			varnames = app.UITable.ColumnName;
			varnames = string(varnames);
			x_idx = varnames == X;
			y_idx = varnames == Y;
			% Get variable units
			dataRef = app.UITable.Data;
			varunits = dataRef.Properties.VariableUnits;
			x_units = varunits(x_idx);
			y_units = varunits(y_idx);
			% Get x and y data
				xData = table2array(dataRef(:,x_idx));
				yData = table2array(dataRef(:,y_idx));
			% Get xmin and xmax
			if iscell(xData) % true for datetimes
				xData = datetime(cell2mat(xData),'InputFormat','YYYY-MM-DD');
				xMin = min(xData);
				xMax = max(xData);
			else
				xMin = min(xData);
				xMax = max(xData);
			end

			% Check if moving average was specified
			value = app.MovingAvgSlider.Value;
			if value > 1
				yData = movmean(yData,value);
			end

			%
			% Begin plotting
			%			
			if strcmp(plt_name,'None')
				plot(app.UIAxes,0:0.1:1,NaN(1,11));
				xlabel(app.UIAxes,'X');
				ylabel(app.UIAxes,'Y');
			elseif strcmp(plt_name,'Bar')
				% Check if there are duplicates in xData (if so, then
				% remove them so that bar() does not produce an error.
				if numel(xData) ~= numel(unique(xData))
					[~,idx]=unique(xData,'stable');
					toDelete=setdiff(1:numel(xData),idx);
					xData(toDelete)=[];
					yData(toDelete)=[];
				end
				bar(app.UIAxes,xData,yData,...
					'FaceColor',colo,...
					'EdgeColor',cont,...
					'LineWidth',0.3,...
					'FaceAlpha',0.7);
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);
			elseif strcmp(plt_name,'Line (default)')
				line(app.UIAxes,xData,yData,...
					'LineWidth',lw,...
					'LineStyle',ls,...
					'Color',colo);
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);
			elseif strcmp(plt_name,'Scatter')
				scatter(app.UIAxes,xData,yData,...
					24,...
					'Marker','o',...
					'MarkerEdgeColor',cont,...
					'LineWidth',1,...
					'MarkerFaceColor',colo,...
					'MarkerFaceAlpha',0.7);
				xlabel(app.UIAxes,x_units);
				ylabel(app.UIAxes,y_units);
				xlim(app.UIAxes,[xMin,xMax]);
			end

			% Change plot title
			app.UIAxes.Title.Interpreter = 'none';
			app.UIAxes.Title.String = [app.IceCoreDropDown.Value,' - ',app.ParametersDropDown_2.Value];

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
			% 
			% This function clears the axes pane
			%
			cla(app.UIAxes)
        end

        % Button pushed function: SaveasButton_2
        function SaveasButton_2Pushed(app, event)
			%
			% This function opens a dialog box for the user to save the
			% chart in the axes pane as an image.
			%
			% Open dialog box
			user_choice = questdlg('Choose a file type:', ...
				'Save as...', ...
				'jpeg','png','tif','jpeg'); % 'Text' is default selection
			% Handle response
			if isempty(user_choice)
				return; % i.e., the user hit 'cancel' so end the loop
			else
				switch user_choice
					case 'jpeg'
        				ext = '.jpg';
					case 'png'
        				ext = '.png';
					case 'tif'
        				ext = '.tif';
				end
				% Get name of core
				core_name = app.IceCoreDropDown.Value;
				% Get name of dataset
				dataset_name = app.DataSetDropDown.Value;
				% Concatenate into file name
				save_file_as_name = [core_name,'_',dataset_name,ext];
	
				% Save file to current folder
				exportgraphics(app.UIAxes,save_file_as_name,'Resolution',300)
			end

        end

        % Button pushed function: MapViewButton
        function MapViewButtonPushed(app, event)
			%
			% This function plots the location of the selected ice core on
			% a geographic axes.
			%
			%
				% Get filename
				pathname = [pwd '/Data/']; 
				extension = '.xlsx';
				switch app.IceCoreDropDown.Value
					case 'Bona-Churchill'
						filename = [pathname,'bona-churchill',extension];
					case 'Bruce Plateau'
						filename = [pathname,'bruce-plateau',extension];
					case 'Dasuopu'
						filename = [pathname,'dasuopu',extension];
					case 'Dunde'
						filename = [pathname,'dunde',extension];
					case 'Elbrus'
						filename = [pathname,'elbrus',extension];
					case 'Guliya'
						filename = [pathname,'guliya',extension];
					case 'IPO Reconstruction'
						filename = [pathname,'interdecadal-pacific-oscillation-index',extension];
					case 'Kilimanjaro'
						filename = [pathname,'kilimanjaro',extension];
					case 'Nevado Huascaran'
						filename = [pathname,'nevado-huascaran',extension];
					case 'Plateau Remote'
						filename = [pathname,'plateau-remote',extension];
					case 'Prince of Wales'
						filename = [pathname,'prince-of-wales',extension];
					case 'Puncak Jaya'
						filename = [pathname,'puncak-jaya',extension];
					case 'Puruogangri'
						filename = [pathname,'puruogangri',extension];
					case 'Quelccaya'
						filename = [pathname,'quelccaya',extension];
					case 'Sajama'
						filename = [pathname,'sajama',extension];
					case 'Siple Station'
						filename = [pathname,'siple-station',extension];
					case 'Taylor Dome'
						filename = [pathname,'taylor-dome',extension];
					case 'Windy Dome'
						filename = [pathname,'windy-dome',extension];
				end

			sheetname = app.DataSetDropDown.Value;
			[~,lat,lon] = readICP(filename,sheetname);

			% Create a new figure and plot the ice core locations on a map
			figure('Name',[app.IceCoreDropDown.Value,' Drilling Location'])
			warning off
			figObject = geoscatter(lat(1),lon(1));
			figObject.Marker = 'o';
			figObject.MarkerEdgeColor = '#f4320c';
			figObject.MarkerFaceAlpha = 0.1;
				% Add second set of markers at same locations, but as open circles
				hold on
				geoscatter(lat(1),lon(1),'Marker','.','MarkerEdgeColor','#f4320c');
			
			gx = figObject.Parent;
			gx.Basemap = 'landcover';
			
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {379, 379};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {266, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EditField = uieditfield(app.LeftPanel, 'text');
            app.EditField.Value = 'Byrd Data Import Tool';
            app.IceCoreDropDownLabel = uilabel(app.LeftPanel);
            app.IceCoreDropDownLabel.Text = 'Ice Core';
            app.IceCoreDropDown = uidropdown(app.LeftPanel);
            app.IceCoreDropDown.Items = {'None', 'Bona-Churchill', 'Bruce Plateau', 'Dasuopu', 'Dunde', 'Elbrus', 'Guliya', 'IPO Reconstruction', 'Kilimanjaro', 'Nevado Huascaran', 'Plateau Remote', 'Prince of Wales', 'Puncak Jaya', 'Puruogangri', 'Quelccaya', 'Sajama', 'Siple Station', 'Taylor Dome', 'Windy Dome'};
            app.IceCoreDropDown.ValueChangedFcn = createCallbackFcn(app, @IceCoreDropDownValueChanged, true);
            app.IceCoreDropDown.Value = 'None';
            app.PublicationDropDownLabel = uilabel(app.LeftPanel);
            app.PublicationDropDownLabel.Text = 'Publication';
            app.PublicationDropDown = uidropdown(app.LeftPanel);
            app.PublicationDropDown.Items = {'None'};
            app.PublicationDropDown.ValueChangedFcn = createCallbackFcn(app, @PublicationDropDownValueChanged, true);
            app.PublicationDropDown.Value = 'None';
            app.ImportButton = uibutton(app.LeftPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.OriginalReferenceTextAreaLabel = uilabel(app.LeftPanel);
            app.OriginalReferenceTextAreaLabel.Text = 'Original Reference';
            app.OriginalReferenceTextArea = uitextarea(app.LeftPanel);
            app.DataSetDropDownLabel = uilabel(app.LeftPanel);
            app.DataSetDropDownLabel.Text = 'Data Set';
            app.DataSetDropDown = uidropdown(app.LeftPanel);
            app.DataSetDropDown.Items = {'None'};
            app.DataSetDropDown.Value = 'None';
            app.PlainTextButton = uibutton(app.LeftPanel, 'push');
            app.PlainTextButton.ButtonPushedFcn = createCallbackFcn(app, @PlainTextButtonPushed, true);
            app.PlainTextButton.Text = 'Plain Text';
            app.BibTeXButton = uibutton(app.LeftPanel, 'push');
            app.BibTeXButton.ButtonPushedFcn = createCallbackFcn(app, @BibTeXButtonPushed, true);
            app.BibTeXButton.Text = 'BibTeX';
            app.IceCoreDropDownLabel_2 = uilabel(app.LeftPanel);
            app.IceCoreDropDownLabel_2.Text = 'Copy as...';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Data Visualization Panel')
            xlabel(app.UIAxes, 'X-Data')
            ylabel(app.UIAxes, 'Y-Data')
            zlabel(app.UIAxes, 'Z')
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.PurpleButton = uiradiobutton(app.ButtonGroup);
            app.PurpleButton.Text = 'Purple';
            app.PurpleButton.Value = true;
            app.RedButton = uiradiobutton(app.ButtonGroup);
            app.RedButton.Text = 'Red';
            app.BlueButton = uiradiobutton(app.ButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uiradiobutton(app.ButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlackButton = uiradiobutton(app.ButtonGroup);
            app.BlackButton.Text = 'Black';
            app.ColorLabel = uilabel(app.ButtonGroup);
            app.ColorLabel.Text = 'Color';
            app.OrangeButton = uiradiobutton(app.ButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.ClearButton = uibutton(app.RightPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveasButton = uibutton(app.RightPanel, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Text = 'Save as...';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ParametersDropDownLabel = uilabel(app.RightPanel);
            app.ParametersDropDownLabel.Text = 'Parameters';
            app.ParametersDropDown = uidropdown(app.RightPanel);
            app.ParametersDropDown.Items = {'None'};
            app.ParametersDropDown.Value = 'None';
            app.ParametersDropDown_2 = uidropdown(app.RightPanel);
            app.ParametersDropDown_2.Items = {'None'};
            app.ParametersDropDown_2.Value = 'None';
            app.ParametersDropDown_3 = uidropdown(app.RightPanel);
            app.ParametersDropDown_3.Items = {'Line (default)', 'Scatter', 'Bar'};
            app.ParametersDropDown_3.Value = 'Line (default)';
            app.SaveasButton_2 = uibutton(app.RightPanel, 'push');
            app.SaveasButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveasButton_2Pushed, true);
            app.SaveasButton_2.Text = 'Save as...';
            app.XDataLabel = uilabel(app.RightPanel);
            app.XDataLabel.Text = 'X-Data';
            app.YDataLabel = uilabel(app.RightPanel);
            app.YDataLabel.Text = 'Y-Data';
            app.ChartStyleLabel = uilabel(app.RightPanel);
            app.ChartStyleLabel.Text = 'Chart Style';
            app.LocationTextAreaLabel = uilabel(app.RightPanel);
            app.LocationTextAreaLabel.Text = 'Location';
            app.LocationTextArea = uitextarea(app.RightPanel);
            app.MapViewButton = uibutton(app.RightPanel, 'push');
            app.MapViewButton.ButtonPushedFcn = createCallbackFcn(app, @MapViewButtonPushed, true);
            app.MapViewButton.Text = 'Map View';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.MovingAvgSliderLabel = uilabel(app.RightPanel);
            app.MovingAvgSliderLabel.Text = 'Moving Avg.';
            app.MovingAvgSlider = uislider(app.RightPanel);
            app.MovingAvgSlider.Limits = [1 10];
            app.MovingAvgSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.MovingAvgSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.MovingAvgSlider.MinorTicks = [];
            app.MovingAvgSlider.Value = 1;
            app.LineWidthSpinnerLabel = uilabel(app.RightPanel);
            app.LineWidthSpinnerLabel.Text = 'Line Width';
            app.LineWidthSpinner = uispinner(app.RightPanel);
            app.LineWidthSpinner.Step = 0.5;
            app.LineWidthSpinner.Limits = [0.5 5];
            app.LineWidthSpinner.Value = 0.5;
            app.LineStyleDropDownLabel = uilabel(app.RightPanel);
            app.LineStyleDropDownLabel.Text = 'Line Style';
            app.LineStyleDropDown = uidropdown(app.RightPanel);
            app.LineStyleDropDown.Items = {'Line', 'Dash', 'Dash-Dot', 'Dot'};
            app.LineStyleDropDown.Value = 'Line';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ByrdApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end